classdef IEC_60601_2_24_plotter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        SettingsPanel                matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        SAVEXLSButton                matlab.ui.control.Button
        Hyperlink                    matlab.ui.control.Hyperlink
        FiltredCheckBox              matlab.ui.control.CheckBox
        AutomaticPlotonchangeVariableCheckBox  matlab.ui.control.CheckBox
        FlowSetmlminSpinnerLabel     matlab.ui.control.Label
        FlowSetmlminSpinner          matlab.ui.control.Spinner
        Transient_timesSpinner       matlab.ui.control.Spinner
        Transient_timesSpinnerLabel  matlab.ui.control.Label
        MaxTimewindowDurationminSpinner  matlab.ui.control.Spinner
        MaxTimewindowDurationminSpinnerLabel  matlab.ui.control.Label
        SampleintervalsSpinner       matlab.ui.control.Spinner
        SampleintervalsSpinnerLabel  matlab.ui.control.Label
        IECPLOTButton                matlab.ui.control.Button
        PlotsPanel                   matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        IEC60601224Label             matlab.ui.control.Label
        UIAxes_Flow                  matlab.ui.control.UIAxes
        UIAxes_Mass                  matlab.ui.control.UIAxes
        UIAxes_Transient             matlab.ui.control.UIAxes
        UIAxes_Trumpet               matlab.ui.control.UIAxes
        LOADXLSButton                matlab.ui.control.Button
    end


    properties (Access = private)
        data; % Description
        name_xls;
    end

    methods (Access = private)

        function T = xls_load(app)
            [filename, pathname] = uigetfile('*.xls', 'Seleziona file Excel');
            if isequal(filename,0) || isequal(pathname,0)
                disp('Operazione annullata dall''utente.');
                return
            end

            fullpath = fullfile(pathname, filename);

            % Legge il file in una table MATLAB
            try
                T = readtable(fullpath);
                disp('File caricato in T (prima righe):');
                disp(head(T)); % mostra le prime righe
                app.name_xls=filename;
                app.IEC60601224Label.Text=filename;
            catch ME
                fprintf('Errore nel leggere il file: %s\n', ME.message);
            end
            % Apri con applicazione esterna (opzionale)

            try
                if ispc
                    winopen(app.SelectedFile);
                else
                    open(app.SelectedFile);
                end
            catch
                % niente: l'utente ha comunque il percorso in app.SelectedFile
            end
        end

        function results = trumpet_windows(app,V, S_s, Qset_mlh, P_list_min)
            % trumpet_windows - IEC 60601-2-24
            %
            % INPUT
            %   V            : volume cumulativo [ml]  (da massa_g / densità)
            %   S_s          : sample interval [s]
            %   Qset_mlh     : portata impostata [ml/h]
            %   P_list_min   : vettore observation window [min]  es. [2 5 11 19 31]
            %
            % OUTPUT
            %   results.P_list  : observation windows [min]
            %   results.Ep_max  : Ep(max) per ogni P [%]
            %   results.Ep_min  : Ep(min) per ogni P [%]
            %   results.Qi      : portata istantanea [ml/h]

            % =========================================================
            % PARAMETRI
            % =========================================================
            d     = 0.998;               % densità acqua a 20°C [g/ml]
            S     = S_s / 60;            % sample interval S [min]  <- norma usa minuti
            r     = Qset_mlh;            % portata impostata r [ml/h]

            % =========================================================
            % FORMULA (1)  - norma eq.(1)
            %
            %        (Wi - W_{i-1})
            %  Qi =  --------------------    [ml/min]
            %               S * d
            %
            %  dove Wi sono i campioni di massa [g]
            %  ma qui lavoriamo con V [ml] = massa/d, quindi:
            %
            %         * (Vi - V_{i-1})
            %  Qi =  --------------------    [ml/min]
            %               S
            % =========================================================
            Wi = V ;                          % massa campionata [g]   W_0 .. W_n
            N  = length(Wi);                     % numero totale campioni

            % i = 1, 2, ... N-1   (norma: i = 1,2,.. T0/S)
            Qi = zeros(N-1, 1);
            for i = 1 : N-1
                Qi(i) = (Wi(i+1) - Wi(i)) / (S );   % [ml/h]
            end

            % numero campioni di portata disponibili
            n_Qi = length(Qi);                   % = N-1
            Tx   = n_Qi * S;                     % durata analisi [min]

            % =========================================================
            % LOOP sulle observation window P
            % =========================================================
            Ep_max  = nan(size(P_list_min));
            Ep_min  = nan(size(P_list_min));

            for k = 1 : length(P_list_min)

                P = P_list_min(k);               % durata finestra [min]

                % =====================================================
                % FORMULA m  - norma
                %
                %       (Tx - P)
                %  m =  --------  + 1
                %          S
                % =====================================================
                m = floor((Tx - P) / S) + 1;

                if m < 1
                    %   warning('P = %g min: nessuna finestra disponibile.', P);
                    continue
                end

                % numero di campioni Qi dentro una finestra di durata P
                nP = round(P / S);               % P/S deve essere intero per la norma

                % =====================================================
                % FORMULA (2) e (3)  - norma
                %
                %              m    [  S    j+P/S-1              ]
                %  Ep(max.) = MAX   [ --- *   Σ    100*(Qi-r)/r  ]   [%]
                %             j=1   [  P    i=j                  ]
                %
                %              m    [  S    j+P/S-1              ]
                %  Ep(min.) = MIN   [ --- *   Σ    100*(Qi-r)/r  ]   [%]
                %             j=1   [  P    i=j                  ]
                % =====================================================
                Ep_j = zeros(1, m);

                for j = 1 : m
                    % indici interni alla finestra:  i = j, j+1, ..., j + P/S - 1
                    i_start = j;
                    i_end   = j + nP - 1;        % = j + P/S - 1

                    % somma interna  Σ 100*(Qi - r)/r
                    somma = 0;
                    for i = i_start : i_end
                        somma = somma + 100 * (Qi(i) - r) / r;
                    end

                    % moltiplica per S/P
                    Ep_j(j) = (S / P) * somma;
                end

                % MAX e MIN su tutte le finestre j = 1..m
                Ep_max(k) = max(Ep_j);
                Ep_min(k) = min(Ep_j);

            end

            % =========================================================
            % OUTPUT
            % =========================================================
            results.P  = P_list_min';
            results.Ep_max  = Ep_max';
            results.Ep_min  = Ep_min';
            results.Qi      = Qi;
            results.Window_size_minutes   = S;
            results.Time_minutes  = Tx;

        end


        function T=plotting_iec(app)

            %% --- SEZIONE 1: Plot massa, flusso e transitorio ---
            try
                % Preparazione dati
                tab = app.data;
                tab.g = tab.g - tab.g(1);
                tab.t = tab.t - tab.t(1);

                % Sottocampionamento
                dt   = median(diff(tab.t));
                step = round(app.SampleintervalsSpinner.Value / dt);
                tab  = tab(1:step:end, :);

                % Derivata del flusso
                dg = 60 * gradient(tab.g) ./ gradient(tab.t);

                % Parametri
                Qset = app.FlowSetmlminSpinner.Value;
                t_trans = app.Transient_timesSpinner.Value;

                % Finestra post-transitorio (per fit lineare)
                [~, idx_trans] = min(abs(tab.t - t_trans));
                x = tab.t(idx_trans:end) / 60;
                y = tab.g(idx_trans:end);
                x = x - x(1);
                y = y - y(1);

                % Fit lineare (slope)
                a   = y(end) / x(end);
                txt = sprintf('m = %.4f', a);

                % --- Plot: massa cumulativa ---
                plot(app.UIAxes_Mass, tab.t, tab.g, "o",app.data.t, app.data.g);
                hold(app.UIAxes_Mass, 'on');
                text(app.UIAxes_Mass, ...
                    min(x) + 0.05*(max(x)-min(x)), ...
                    max(y) - 0.05*(max(y)-min(y)), ...
                    txt, 'Color','r', 'FontWeight','bold');
                hold(app.UIAxes_Mass, 'off');

                % --- Plot: flusso istantaneo ---
                plot(app.UIAxes_Flow, tab.t, dg);
                % ylim(app.UIAxes_Flow, [-0.2*Qset, 2*Qset]);
                % app.UIAxes_Flow.YTick = (-0.2*Qset) : (0.2*Qset) : (2*Qset);

                add_qset_line(app,app.UIAxes_Flow, Qset);

                x1 = xline(app.UIAxes_Flow, t_trans, '-.');
                % opzioni aggiuntive (colore, larghezza, etichetta)
                x1.Color = 'r';
                x1.LineWidth = 1.5;
                x1.Label = 'transient';


                % --- Plot: transitorio ---
                plot(app.UIAxes_Transient, tab.t(1:idx_trans), dg(1:idx_trans));
                % ylim(app.UIAxes_Transient, [-0.2*Qset, 2*Qset]);
                % app.UIAxes_Transient.YTick = (-0.2*Qset) : (0.2*Qset) : (2*Qset);
                add_qset_line(app,app.UIAxes_Transient, Qset);

            catch exception
                % TODO: gestione errori
            end

            %% --- SEZIONE 2: Trumpet plot (IEC 60601) ---
            try
                i= tab.t>t_trans;
                dg_i= dg(i);
                dt_i=diff(tab.t(i));
                % g_filtr=cumsum(movmean( tab.g(i),3));
                g_filtr=cumsum(dt_i.*movmean(dg_i(1:end-1)/60,3));
                if app.FiltredCheckBox.Value

                    V      = g_filtr;%tab.g(i);
                else

                    V      = tab.g(i);
                end


                disp(size(V))
                S      = app.SampleintervalsSpinner.Value;
                Qset   = app.FlowSetmlminSpinner.Value;
                P_list = 1 : app.MaxTimewindowDurationminSpinner.Value;

                T = trumpet_windows(app, V, S, Qset, P_list);
                r = mean([T.Ep_min; T.Ep_max], 'omitnan');

                % Plot min/max e linee di riferimento
                plot(app.UIAxes_Trumpet, T.P, T.Ep_min, '-*');
                hold(app.UIAxes_Trumpet, 'on');
                plot(app.UIAxes_Trumpet, T.P, T.Ep_max, '-*');
                yline(app.UIAxes_Trumpet, 0, '-.');


                % T.P
                % T.Ep_min
                % T.Ep_max
                % figure
                % plot(T.Ep_mean)
                % figure
                % plot(T.Ep_all  )

                T.Flowrate= [tab.t, dg];
                T.Mass= [ tab.t, tab.g];

                % Linea media con etichetta
                h = yline(app.UIAxes_Trumpet, r);
                h.Label                    = sprintf('%.2f', r);
                h.LabelHorizontalAlignment = 'right';
                h.LabelVerticalAlignment   = 'middle';

                % Formattazione assi
                ylabel(app.UIAxes_Trumpet, 'Flow Variation (%)');
                xlim(app.UIAxes_Trumpet, [0, P_list(end)]);
                ylim(app.UIAxes_Trumpet, [-15, 15]);
                yticks(app.UIAxes_Trumpet, -15:5:15);
                grid(app.UIAxes_Trumpet, 'on');
                hold(app.UIAxes_Trumpet, 'off');

            catch exception
                % TODO: gestione errori
            end

        end

        %% --- Helper: aggiunge linea orizzontale Qset ---
        function add_qset_line(app,ax, Qset)


            ylim(ax, [-0.2*Qset, 2*Qset]);
            ax.YTick = (-0.2*Qset) : (0.2*Qset) : (2*Qset);

            yl            = yline(ax, Qset, '-.');
            yl.Color      = 'r';
            yl.LineWidth  = 1.5;
            yl.Label      = 'Q_{set}';
        end


        function TT = saveOutputData(app)
            % Esporta i dati di log dal CSV temporaneo in un file scelto dall'utente
            % Per Excel aggiunge una colonna 't' con il tempo relativo in secondi

            outPath     = app.filePath;
            outName     = 'output_bilancia.xls';
            defaultName = fullfile(pwd, outName);

            [saveName, savePath] = uiputfile( ...
                {'*.xls;*.xlsx', 'Excel files (*.xls, *.xlsx)'; ...
                '*.csv',        'CSV files (*.csv)'; ...
                '*.*',          'All Files (*.*)'}, ...
                'Salva una copia come', defaultName);

            if isequal(saveName, 0) || isequal(savePath, 0)
                % L'utente ha annullato la finestra di dialogo
                uialert(app.UIFigure, 'Salvataggio annullato dall''utente.', ...
                    'saveDatas', "Icon", "warning");
            else
                [~, ~, ext] = fileparts(saveName);
                if isempty(ext)
                    ext      = '.xls';
                    saveName = [saveName, ext]; % Aggiunge estensione di default
                end
                destFull = fullfile(savePath, saveName);

                try
                    if any(strcmpi(ext, {'.xls', '.xlsx'}))
                        % Legge il CSV temporaneo, calcola il tempo relativo e salva in Excel
                        TT   = readtable(outPath);
                        time = TT.h .* 3600 + TT.m .* 60 + TT.s;
                        TT.t = time - time(1); % Tempo relativo a partire da 0
                        writetable(TT, destFull, 'FileType', 'spreadsheet');
                    else
                        copyfile(outPath, destFull); % Copia diretta per CSV e altri formati
                    end
                    msg = sprintf('Copia salvata in:\n%s', destFull);
                    uialert(app.UIFigure, msg, 'Save Data', 'Icon', 'success');
                    initLogging(app); % Azzera il file temporaneo dopo il salvataggio
                catch
                    uialert(app.UIFigure, 'Errore durante il salvataggio', 'saveDatas');
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LOADXLSButton
        function LOADXLSButtonPushed(app, event)
            try

                app.data=xls_load(app);
                plotting_iec(app);
            catch exception

            end
        end

        % Button pushed function: IECPLOTButton
        function IECPLOTButtonPushed(app, event)
            plotting_iec(app);
        end

        % Value changed function: FiltredCheckBox, FlowSetmlminSpinner, 
        % ...and 3 other components
        function Automatic_IECPLOT(app, event)
            if app.AutomaticPlotonchangeVariableCheckBox.Value
                plotting_iec(app);
            end
        end

        % Button pushed function: SAVEXLSButton
        function SAVEXLSButtonPushed(app, event)


    T = plotting_iec(app);
    C = {'Flowrate', 'Mass', 'P', 'Ep_max', 'Ep_min', 'Qi', 'Window_size_minutes', 'Time_minutes'};
   Vars_header = {'Flowrate(ml/min)', 'Mass(g)', 'Window Size (min)', 'Errore %','Errore %', 'Flusso istantaneo (ml/min)', 'Window_size (min)', 'Tempo Totale (min)'};
    T = orderfields(T, C);
base = app.name_xls;

[~, name, ext] = fileparts(base);
if isempty(ext)
    defname = [name '_IEC60601.xlsx'];
else
    defname = [name '_IEC60601' ext];
end
        [file, path] = uiputfile('*.xlsx', 'Salva file Excel', defname);


    if isequal(file, 0) || isequal(path, 0)
        uialert(app.UIFigure, 'Salvataggio annullato', 'Save Data', 'Icon', 'warning');
    else
        excelFilename = fullfile(path, file);
        structFieldnames = fieldnames(T);

        for k = 1:numel(structFieldnames)
            fieldname = structFieldnames{k};
            var_h=Vars_header{k};
            data = T.(fieldname);
            sheetName = sprintf('%s', fieldname);

            % Scegli l'header in base alla forma dei dati
            if isscalar(data)
                % Scalare: una sola colonna
                header = {var_h};
            elseif size(data, 2) == 2
                % n×2: colonna tempo + variabile
                header = {'Time (s)', var_h};
            else
                % n×1: una sola colonna
                header = {var_h};
            end

            % Scrivi header nella riga 1
            writecell(header, excelFilename, 'Sheet', sheetName, 'Range', 'A1');

            % Scrivi i dati dalla riga 2
            writematrix(data, excelFilename, 'Sheet', sheetName, 'Range', 'A2');
        end

        msg = sprintf('Salvato in:\n%s', excelFilename);
        uialert(app.UIFigure, msg, 'Save Data', 'Icon', 'success');
    end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LOADXLSButton = uibutton(app.GridLayout, 'push');
            app.LOADXLSButton.ButtonPushedFcn = createCallbackFcn(app, @LOADXLSButtonPushed, true);
            app.LOADXLSButton.Text = 'LOAD XLS';
            app.PlotsPanel = uipanel(app.GridLayout);
            app.PlotsPanel.Title = 'Plots';
            app.GridLayout2 = uigridlayout(app.PlotsPanel);
            app.UIAxes_Trumpet = uiaxes(app.GridLayout2);
            title(app.UIAxes_Trumpet, 'IEC 60601-2-24: TRUMPET')
            xlabel(app.UIAxes_Trumpet, 'Observation Window(min)')
            ylabel(app.UIAxes_Trumpet, 'Error %')
            zlabel(app.UIAxes_Trumpet, 'Z')
            app.UIAxes_Transient = uiaxes(app.GridLayout2);
            title(app.UIAxes_Transient, 'IEC 60601-2-24: TRANSIENT')
            xlabel(app.UIAxes_Transient, 'Time(s)')
            ylabel(app.UIAxes_Transient, 'Flowrate(ml/min)')
            zlabel(app.UIAxes_Transient, 'Z')
            app.UIAxes_Mass = uiaxes(app.GridLayout2);
            title(app.UIAxes_Mass, 'Mass')
            xlabel(app.UIAxes_Mass, 'Time(s)')
            ylabel(app.UIAxes_Mass, 'Mass(g)')
            zlabel(app.UIAxes_Mass, 'Z')
            app.UIAxes_Flow = uiaxes(app.GridLayout2);
            title(app.UIAxes_Flow, 'Flowrate')
            xlabel(app.UIAxes_Flow, 'Time(s)')
            ylabel(app.UIAxes_Flow, 'Flowrate(ml/min)')
            zlabel(app.UIAxes_Flow, 'Z')
            app.IEC60601224Label = uilabel(app.GridLayout2);
            app.IEC60601224Label.Text = 'IEC 60601-2-24';
            app.SettingsPanel = uipanel(app.GridLayout);
            app.SettingsPanel.Title = 'Settings';
            app.GridLayout3 = uigridlayout(app.SettingsPanel);
            app.IECPLOTButton = uibutton(app.GridLayout3, 'push');
            app.IECPLOTButton.ButtonPushedFcn = createCallbackFcn(app, @IECPLOTButtonPushed, true);
            app.IECPLOTButton.Text = 'IEC PLOT';
            app.SampleintervalsSpinnerLabel = uilabel(app.GridLayout3);
            app.SampleintervalsSpinnerLabel.Text = 'Sample interval (s)';
            app.SampleintervalsSpinner = uispinner(app.GridLayout3);
            app.SampleintervalsSpinner.ValueChangedFcn = createCallbackFcn(app, @Automatic_IECPLOT, true);
            app.SampleintervalsSpinner.Value = 5;
            app.MaxTimewindowDurationminSpinnerLabel = uilabel(app.GridLayout3);
            app.MaxTimewindowDurationminSpinnerLabel.Text = 'Max Time window Duration(min)';
            app.MaxTimewindowDurationminSpinner = uispinner(app.GridLayout3);
            app.MaxTimewindowDurationminSpinner.ValueChangedFcn = createCallbackFcn(app, @Automatic_IECPLOT, true);
            app.MaxTimewindowDurationminSpinner.Value = 31;
            app.Transient_timesSpinnerLabel = uilabel(app.GridLayout3);
            app.Transient_timesSpinnerLabel.Text = 'Transient_time (s)';
            app.Transient_timesSpinner = uispinner(app.GridLayout3);
            app.Transient_timesSpinner.ValueChangedFcn = createCallbackFcn(app, @Automatic_IECPLOT, true);
            app.Transient_timesSpinner.Value = 120;
            app.FlowSetmlminSpinner = uispinner(app.GridLayout3);
            app.FlowSetmlminSpinner.ValueChangedFcn = createCallbackFcn(app, @Automatic_IECPLOT, true);
            app.FlowSetmlminSpinner.Value = 38;
            app.FlowSetmlminSpinnerLabel = uilabel(app.GridLayout3);
            app.FlowSetmlminSpinnerLabel.Text = 'FlowSet (ml/min)';
            app.AutomaticPlotonchangeVariableCheckBox = uicheckbox(app.GridLayout3);
            app.AutomaticPlotonchangeVariableCheckBox.Text = 'Automatic Plot on change Variable';
            app.FiltredCheckBox = uicheckbox(app.GridLayout3);
            app.FiltredCheckBox.ValueChangedFcn = createCallbackFcn(app, @Automatic_IECPLOT, true);
            app.FiltredCheckBox.Text = 'Filtred';
            app.Hyperlink = uihyperlink(app.GridLayout3);
            app.Hyperlink.URL = 'https://download.s21i.co99.net/32352161/0/0/ABUIABA9GAAg2M_isQYosIrd4QE.pdf?f=IEC%2060601-2-24-1998.pdf&v=1713940440';
            app.Hyperlink.Text = 'Download IEC 60601-2-24 Documentation';
            app.SAVEXLSButton = uibutton(app.GridLayout3, 'push');
            app.SAVEXLSButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEXLSButtonPushed, true);
            app.SAVEXLSButton.Text = 'SAVE XLS';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IEC_60601_2_24_plotter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end