classdef Scale_Acquisition < matlab.apps.AppBase
    %SCALE_ACQUISITION Sistema di dosaggio a due pompe
    %   Questa applicazione consente di controllare due pompe peristaltiche
    %   tramite comunicazione seriale UART con un microcontrollore.
    %   
    %   --- FLUSSO DI LAVORO ---
    %   1. Collega il microcontrollore via USB
    %   2. Premi "Connessione" per stabilire il collegamento seriale
    %   3. Imposta le portate (Portata 1 / Portata 2) o il TFR e FRR
    %   4. Imposta le masse massime nel pannello "Volumi massimi"
    %   5. Attiva "Start Logging" per registrare i dati su file CSV
    %   6. Premi "Start Production" per avviare il dosaggio
    %   7. Al termine, premi "Salva" per esportare i dati in Excel
    %   
    %   --- PROTOCOLLO SERIALE ---
    %   Baud rate: 115200
    %   Pacchetto RX: [A] [9 float32 = 36 byte] [LF]  →  38 byte totali
    %   Valori ricevuti: dt, q1, V1, q2, V2, flusso1, flusso2, massa1,
    %   massa2
    %   
    %   --- REQUISITI ---
    %   MATLAB R2021a+  |  Instrument Control Toolbox
    %   
    %   Per assistenza contattare l'autore del progetto.

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        DeveloperPanel                 matlab.ui.container.Panel
        GridLayout1                    matlab.ui.container.GridLayout
        SerialeBilanciaEditField       matlab.ui.control.EditField
        SerialeBilanciaEditFieldLabel  matlab.ui.control.Label
        developermodeCheckBox          matlab.ui.control.CheckBox
        Lamp                           matlab.ui.control.Lamp
        FunzionamentoPanel             matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        LaunchPanel                    matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        SalvaButton                    matlab.ui.control.Button
        StartLoggingButton             matlab.ui.control.StateButton
        TabGroup                       matlab.ui.container.TabGroup
        PortateerogateTab              matlab.ui.container.Tab
        GridLayout4                    matlab.ui.container.GridLayout
        VolumimassimiPanel             matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        MassaEditField                 matlab.ui.control.NumericEditField
        MassaEditFieldLabel            matlab.ui.control.Label
        ImpostavolumimassimiButton     matlab.ui.control.Button
        VolumiErogatiPanel             matlab.ui.container.Panel
        GridLayout6                    matlab.ui.container.GridLayout
        VolumeGauge                    matlab.ui.control.SemicircularGauge
        VolumeGaugeLabel               matlab.ui.control.Label
        VolumeEditField                matlab.ui.control.NumericEditField
        GraficoTab                     matlab.ui.container.Tab
        GridLayout7                    matlab.ui.container.GridLayout
        UIAxes_values2                 matlab.ui.control.UIAxes
        UIAxes_values                  matlab.ui.control.UIAxes
        ConnessionePanel               matlab.ui.container.Panel
        GridLayout8                    matlab.ui.container.GridLayout
        ConnessioneButton              matlab.ui.control.StateButton
        ThemeButton                    matlab.ui.control.Button
        listaSeriale                   matlab.ui.control.DropDown
        ListaBleLabel_2                matlab.ui.control.Label
    end

    properties (Access = private)
        theme=1;
        values                 % Ultimi 9 valori float ricevuti via seriale: [dt, q1, V1, q2, V2, f1, f2, m1, m2]
        mass=zeros(3,1);       % Vettore masse [m1, m2, mtot] — azzerato a ogni reset
        fid = -1               % File descriptor per il logging CSV (-1 = file chiuso)
        t;                     % Tempo corrente del grafico, incrementato ogni tick del timer
        % --- Seriale ---
        timerObj_uart;         % Timer MATLAB per il refresh del grafico a intervalli fissi
        ardObj;                % Oggetto serialport per la comunicazione con Arduino

        % --- Acquisizione ---

        animLine_f1            % Linea animata del grafico pompa 1
        animLine_f2            % Linea animata del grafico pompa 2
        dt=5;                % Periodo del timer in secondi (= frequenza di aggiornamento grafico)

        filePath               % Percorso del file CSV temporaneo di logging
        HLine_f1;              % Linea orizzontale setpoint pompa 1 sul grafico
        HLine_f2;              % Linea orizzontale setpoint pompa 2 sul grafico
        old_m1=0; % Description
    end

    methods (Access = private)

        function disconnection(app)
            % Disconnette il dispositivo seriale e ferma il timer di aggiornamento
            try
                clear app.ardObj                        % Rilascia la porta seriale
                app.ardObj=[];
                app.FunzionamentoPanel.Enable = "off";  % Disabilita la UI di controllo
                stop(app.timerObj_uart);                % Ferma il timer
                %delete(app.timerObj_uart);              % Distrugge l'oggetto timer
                %app.timerObj_uart = [];                 % Azzera il riferimento
            catch
                uialert(app.UIFigure, 'Errore durante disconnessione.', 'Errore');
            end
        end

        function timer_plot(app)
            % Azzera il contatore temporale e avvia il timer di aggiornamento grafico
            try
                app.t = 0;
                start(app.timerObj_uart);
            catch
                uialert(app.UIFigure, 'timer_plot', 'Errore');
            end
        end

        % Indici valori ricevuti dal firmware:
        % [dt, q1, V1, q2, V2, flusso1, flusso2, massa1, massa2]
        %   1   2   3   4   5     6       7         8       9
        function updatePlot(app)
            % Chiamata dal timer ogni dt secondi: aggiorna text area, gauge e grafici

            % app.SerialeTextArea.Value = num2str(app.values);%,'%.3f '); % Mostra i raw data

            try

                app.UITable.Data =app.values;
            catch exception

            end
            if app.StartLoggingButton.Value  % Aggiorna solo se il logging è attivo
                if isempty(app.t)
                    app.t = 0;
                end

                try
                    % app.SerialeTextArea.Value = num2str(app.values);
                    m1 = app.values(1);                           % Flusso misurato pompa 1
                    f1=60*(m1-app.old_m1)/app.dt;
                    %app.VolumeGauge.Value    = app.values(1);    % Massa erogata pompa 1
                    app.old_m1=m1;

                    app.t = app.t + app.dt;        % Avanza il tempo di un passo
                catch
                    warning("on","errore!")
                    m1 = 0; f1 = 0; % Valori di sicurezza in caso di dati mancanti
                end

                addpoints(app.animLine_f1, app.t, m1); % Aggiunge punto grafico pompa 1
                addpoints(app.animLine_f2, app.t, f1); % Aggiunge punto grafico pompa 2
                drawnow limitrate nocallbacks           % Aggiorna il rendering senza bloccare la UI

                % app.TimeGauge.Value = app.t;   % Aggiorna il gauge del tempo
            end

        end


        function connection_serial(app)
            % Apre la connessione seriale, configura il callback di ricezione,
            % sincronizza l'orologio e invia il comando STOP iniziale
            clear app.ardObj
            try
                d = uiprogressdlg(app.UIFigure, 'Title', 'Connection', 'Indeterminate', 'on');
                COM_port  = app.listaSeriale.Value;
                BAUD_RATE = 9600;
                app.ardObj = serialport(COM_port,BAUD_RATE,"Parity","None","DataBits",8,"StopBits",1);
                app.ardObj.ErrorOccurredFcn = @app.disconnessione_cheker; % Callback errore USB

                flush(app.ardObj); % Svuota il buffer seriale residuo

                % Callback attivato ogni fine riga
                %configureCallback(app.ardObj, "byte", 38, @app.readSerialDataUART);

                configureCallback(app.ardObj,"terminator",@app.onTerminator);

                app.FunzionamentoPanel.Enable = "on"; % Sblocca la UI di controllo

                timer_plot(app);                   % Avvia il timer di aggiornamento grafico
                close(d);                          % Chiude la finestra di progresso
            catch
                uialert(app.UIFigure, 'connection_serial', 'Errore');
            end
        end

        function connection(app)
            % Wrapper per connection_serial con gestione errore aggiuntiva
            try
                connection_serial(app);
            catch
                uialert(app.UIFigure, 'connection(app)', 'Errore');
            end
        end


        function onTerminator(app,src,~,~)
            s = readline(src);
            try

                app.SerialeBilanciaEditField.Value=(s);
            catch exception

            end
            %app.SerialeBilanciaEditField.Value=s;
            % Estrai numero (accetta segno, virgola o punto, decimali, esponente)
            token = regexp(s, '([+-]?\d+[.,]?\d*(?:[eE][+-]?\d+)?)', 'match', 'once');

            % Normalizza la virgola a punto e converti
            token = strrep(token, ',', '.');
            grams = str2double(token);
            app.values=grams;
            app.VolumeGauge.Value=grams;
            app.VolumeEditField.Value=grams;

            %fprintf("Grams = %g  Bytes: %d\n", char(ts), s, grams, src.NumBytesAvailable);

            if app.StartLoggingButton.Value && app.fid > 0
                c = datetime("now");
                fprintf(app.fid, '%02d,%02d,%06.3f,%g\n', ...
                    c.Hour, c.Minute, c.Second,grams);
            end
        end

        function disconnessione_cheker(app, src, event, name)
            % Callback di errore seriale: notifica l'utente e azzera l'oggetto porta
            uialert(app.UIFigure, 'Error', 'Usb Disconnection');
            app.ardObj = [];
        end

        function TT = saveOutputData(app)
            % Esporta i dati di log dal CSV temporaneo in un file scelto dall'utente
            % Per Excel aggiunge una colonna 't' con il tempo relativo in secondi

            outPath     = app.filePath;
            outName     = 'output_bilancia.xls';
            defaultName = fullfile(pwd, outName);

            [saveName, savePath] = uiputfile( ...
                {'*.xls;*.xlsx', 'Excel files (*.xls, *.xlsx)'; ...
                '*.csv',        'CSV files (*.csv)'; ...
                '*.*',          'All Files (*.*)'}, ...
                'Salva una copia come', defaultName);

            if isequal(saveName, 0) || isequal(savePath, 0)
                % L'utente ha annullato la finestra di dialogo
                uialert(app.UIFigure, 'Salvataggio annullato dall''utente.', ...
                    'saveDatas', "Icon", "warning");
            else
                [~, ~, ext] = fileparts(saveName);
                if isempty(ext)
                    ext      = '.xls';
                    saveName = [saveName, ext]; % Aggiunge estensione di default
                end
                destFull = fullfile(savePath, saveName);

                try
                    if any(strcmpi(ext, {'.xls', '.xlsx'}))
                        % Legge il CSV temporaneo, calcola il tempo relativo e salva in Excel
                        TT   = readtable(outPath);
                        time = TT.h .* 3600 + TT.m .* 60 + TT.s;
                        TT.t = time - time(1); % Tempo relativo a partire da 0
                        writetable(TT, destFull, 'FileType', 'spreadsheet');
                    else
                        copyfile(outPath, destFull); % Copia diretta per CSV e altri formati
                    end
                    msg = sprintf('Copia salvata in:\n%s', destFull);
                    uialert(app.UIFigure, msg, 'Save Data', 'Icon', 'success');
                    initLogging(app); % Azzera il file temporaneo dopo il salvataggio
                catch
                    uialert(app.UIFigure, 'Errore durante il salvataggio', 'saveDatas');
                end
            end
        end

        function initLogging(app)
            % Crea o azzera il file CSV temporaneo di logging (sovrascrittura)
            fname = fullfile(tempdir, "output_bilancia.csv");
            f     = fopen(fname, "w"); % Apertura in scrittura: cancella il contenuto precedente
            fclose(f);
            app.filePath = fullfile(tempdir, 'output_bilancia.csv');
        end

        function StartStopLogging(app, stato)
            % Apre il file di log in append (stato=true) o lo chiude (stato=false)
            if stato
                app.filePath = fullfile(tempdir, 'output_bilancia.csv');
                app.fid      = fopen(app.filePath, 'a'); % Append: non sovrascrive i dati precedenti
                fprintf(app.fid, 'h,m,s,g\n'); % Intestazione CSV
            else
                if app.fid > 0
                    fclose(app.fid); % Chiude e libera il file descriptor
                    app.fid = -1;
                end
            end
        end

        function setAppFontToConsolas(app)
            % Imposta il font 'Segoe UI' su tutti i componenti della UI che lo supportano
            % Utile per uniformare lo stile grafico dell'intera applicazione
            allComponents = findall(app.UIFigure); % Recupera tutti i componenti figli
            for i = 1:numel(allComponents)
                if isprop(allComponents(i), 'FontName') % Controlla se la proprietà esiste
                    allComponents(i).FontName = 'Segoe UI';
                end
            end
        end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc; % Pulisce il Command Window all'avvio

            % --- Impostazione font globale ---
            app.setAppFontToConsolas(); % Applica 'Segoe UI' a tutti i componenti della UI

            % --- Centratura e ridimensionamento della finestra ---
            screen = get(0, 'ScreenSize');   % Ottiene la risoluzione dello schermo [x y w h]
            w = screen(3) * 0.8;             % Larghezza = 80% dello schermo
            h = screen(4) * 0.8;             % Altezza   = 80% dello schermo
            x = (screen(3) - w) / 2;        % Coordinata x per centrare orizzontalmente
            y = (screen(4) - h) / 2;        % Coordinata y per centrare verticalmente
            app.UIFigure.Position = [x, y, w, h];

            % --- Inizializzazione logging ---
            initLogging(app);                       % Crea/azzera il file CSV temporaneo
            app.StartLoggingButton.Value = false;   % Logging disattivo all'avvio

            % --- Creazione del timer di aggiornamento grafico ---
            app.timerObj_uart = timer(...
                'ExecutionMode', 'fixedrate', ...            % Esecuzione a intervalli fissi
                'Period',        app.dt, ...                 % Periodo = dt (0.2 s → 5 Hz)
                'TimerFcn',      @(src,event) updatePlot(app)); % Chiama updatePlot ad ogni tick

            % --- Configurazione assi grafico pompa 1 ---
            app.UIAxes_values.XLimMode    = "auto";    % Scala X automatica
            app.UIAxes_values.XLimitMethod = "padded"; % Aggiunge margine ai bordi
            app.UIAxes_values.YLimMode    = "auto";    % Scala Y automatica

            % --- Configurazione assi grafico pompa 2 ---
            app.UIAxes_values2.XLimMode    = "auto";
            app.UIAxes_values2.XLimitMethod = "padded";
            app.UIAxes_values2.YLimMode    = "auto";

            % --- Scansione porte seriali disponibili ---
            % uart_serial_reader cerca il dispositivo su tutte le porte e
            % preseleziona quella corretta nel dropdown
            app.listaSeriale.Items = serialportlist("available");

            % --- Linee animate per i grafici in tempo reale ---
            % MaximumNumPoints limita la finestra visibile a 5 secondi di dati
            app.animLine_f1 = animatedline(app.UIAxes_values,  'Color', 'b', ...
                'Marker', '*', 'MaximumNumPoints', floor(50/app.dt));
            app.animLine_f2 = animatedline(app.UIAxes_values2, 'Color', 'b', ...
                'Marker', '*', 'MaximumNumPoints', floor(50/app.dt));

            % % --- Linea orizzontale setpoint pompa 1 ---
            % app.HLine_f1 = yline(app.UIAxes_values, 0, '--r', 'Setpoint');
            % app.HLine_f1.LineWidth = 2;
            % app.HLine_f1.LabelHorizontalAlignment = 'left'; % Etichetta allineata a sinistra
            %
            % % --- Linea orizzontale setpoint pompa 2 ---
            % app.HLine_f2 = yline(app.UIAxes_values2, 0, '--r', 'Setpoint');
            % app.HLine_f2.LineWidth = 2;
            % app.HLine_f2.LabelHorizontalAlignment = 'left';

        end

        % Value changed function: ConnessioneButton
        function ConnessioneButtonValueChanged(app, event)
            value = app.ConnessioneButton.Value;
            if(value==1) %%  pressed
                app.ConnessioneButton.BackgroundColor=[0.39,0.83,0.07];
                connection(app);

            else %% not pressed
                disconnection(app);
                app.ConnessioneButton.BackgroundColor=[1.00,0.41,0.16];

            end
            %ConnessioneButtonValueChanged
        end

        % Button pushed function: ImpostavolumimassimiButton
        function ImpostavolumimassimiButtonPushed(app, event)
            % Imposta il range del gauge
            app.VolumeGauge.Limits = [0 app.MassaEditField.Value];


        end

        % Value changed function: MassaEditField
        function MassaEditFieldValueChanged(app, event)
            % Imposta il range del gauge
            app.VolumeGauge.Limits = [0 app.MassaEditField.Value];

        end

        % Drop down opening function: listaSeriale
        function listaSerialeDropDownOpening(app, event)

            app.listaSeriale.Items=serialportlist("available");


        end

        % Button pushed function: ThemeButton
        function ThemeButtonPushed(app, event)

            if app.theme  == 0
                app.UIFigure.Theme = "light";
                app.theme  = 1;
            else
                app.UIFigure.Theme = "dark";
                app.theme  = 0;
            end

        end

        % Value changed function: StartLoggingButton
        function StartLoggingButtonValueChanged(app, event)

            stato = app.StartLoggingButton.Value;
            if stato
                app.StartLoggingButton.Text = "Logging";
            else
                app.StartLoggingButton.Text = "Not Logging";
            end

            try
                StartStopLogging(app,stato)
            catch ME
                report = getReport(ME, 'extended', 'hyperlinks', 'on');
                uialert(app.UIFigure, report, 'Errore', 'Interpreter', 'html');
            end
        end

        % Button pushed function: SalvaButton
        function SalvaButtonPushed(app, event)
            saveOutputData(app);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ConnessionePanel = uipanel(app.GridLayout);
            app.ConnessionePanel.Title = 'Connessione';
            app.GridLayout8 = uigridlayout(app.ConnessionePanel);
            app.ListaBleLabel_2 = uilabel(app.GridLayout8);
            app.ListaBleLabel_2.Text = 'Lista Seriale';
            app.listaSeriale = uidropdown(app.GridLayout8);
            app.listaSeriale.Items = {'COM'};
            app.listaSeriale.DropDownOpeningFcn = createCallbackFcn(app, @listaSerialeDropDownOpening, true);
            app.listaSeriale.Tooltip = {'Seleziona la porta COM del microcontrollore. L''elenco si aggiorna automaticamente all''apertura.'};
            app.listaSeriale.Value = 'COM';
            app.ThemeButton = uibutton(app.GridLayout8, 'push');
            app.ThemeButton.ButtonPushedFcn = createCallbackFcn(app, @ThemeButtonPushed, true);
            app.ThemeButton.Text = 'Theme';
            app.ConnessioneButton = uibutton(app.GridLayout8, 'state');
            app.ConnessioneButton.ValueChangedFcn = createCallbackFcn(app, @ConnessioneButtonValueChanged, true);
            app.ConnessioneButton.Tooltip = {'Premi per connetterti al dispositivo. Diventa verde se la connessione ha successo. Ripremi per disconnetterti.'};
            app.ConnessioneButton.Text = 'Connessione';
            app.FunzionamentoPanel = uipanel(app.GridLayout);
            app.FunzionamentoPanel.Title = 'Funzionamento';
            app.GridLayout2 = uigridlayout(app.FunzionamentoPanel);
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.PortateerogateTab = uitab(app.TabGroup);
            app.PortateerogateTab.Title = 'Portate erogate';
            app.GridLayout4 = uigridlayout(app.PortateerogateTab);
            app.VolumiErogatiPanel = uipanel(app.GridLayout4);
            app.VolumiErogatiPanel.Title = 'Volumi  Erogati';
            app.GridLayout6 = uigridlayout(app.VolumiErogatiPanel);
            app.VolumeEditField = uieditfield(app.GridLayout6, 'numeric');
            app.VolumeEditField.Editable = 'off';
            app.VolumeGaugeLabel = uilabel(app.GridLayout6);
            app.VolumeGaugeLabel.Text = 'Volume';
            app.VolumeGauge = uigauge(app.GridLayout6, 'semicircular');
            app.VolumeGauge.Limits = [0 1000];
            app.VolumeGauge.Tooltip = {'Massa erogata dalla pompa 1 in tempo reale (g).'};
            app.VolumimassimiPanel = uipanel(app.GridLayout4);
            app.VolumimassimiPanel.Title = 'Volumi massimi';
            app.GridLayout5 = uigridlayout(app.VolumimassimiPanel);
            app.ImpostavolumimassimiButton = uibutton(app.GridLayout5, 'push');
            app.ImpostavolumimassimiButton.ButtonPushedFcn = createCallbackFcn(app, @ImpostavolumimassimiButtonPushed, true);
            app.ImpostavolumimassimiButton.Tooltip = {'Aggiorna i limiti dei gauge in base ai valori di Massa 1, Massa 2 e Massa Tot inseriti.'};
            app.ImpostavolumimassimiButton.Text = 'Imposta volumi massimi';
            app.MassaEditFieldLabel = uilabel(app.GridLayout5);
            app.MassaEditFieldLabel.Text = 'Massa';
            app.MassaEditField = uieditfield(app.GridLayout5, 'numeric');
            app.MassaEditField.ValueChangedFcn = createCallbackFcn(app, @MassaEditFieldValueChanged, true);
            app.MassaEditField.Tooltip = {'Massa massima da erogare con la pompa 1 (g). Imposta il limite superiore del gauge Volume 1.'};
            app.MassaEditField.Value = 1000;
            app.GraficoTab = uitab(app.TabGroup);
            app.GraficoTab.Title = 'Grafico';
            app.GridLayout7 = uigridlayout(app.GraficoTab);
            app.UIAxes_values = uiaxes(app.GridLayout7);
            title(app.UIAxes_values, 'Mass')
            xlabel(app.UIAxes_values, 'Time(s)')
            ylabel(app.UIAxes_values, 'Mass(g)')
            zlabel(app.UIAxes_values, 'Z')
            app.UIAxes_values.XLimitMethod = 'padded';
            app.UIAxes_values2 = uiaxes(app.GridLayout7);
            title(app.UIAxes_values2, 'Flow')
            xlabel(app.UIAxes_values2, 'Time(s)')
            ylabel(app.UIAxes_values2, 'ml/min')
            zlabel(app.UIAxes_values2, 'Z')
            app.UIAxes_values2.XLimitMethod = 'padded';
            app.LaunchPanel = uipanel(app.GridLayout2);
            app.LaunchPanel.Title = 'Launch';
            app.GridLayout3 = uigridlayout(app.LaunchPanel);
            app.StartLoggingButton = uibutton(app.GridLayout3, 'state');
            app.StartLoggingButton.ValueChangedFcn = createCallbackFcn(app, @StartLoggingButtonValueChanged, true);
            app.StartLoggingButton.Tooltip = {'Avvia/ferma la registrazione dati su file CSV temporaneo. Ricordati di salvare con il pulsante "Salva" prima di chiudere.'};
            app.StartLoggingButton.Text = 'Start Logging';
            app.SalvaButton = uibutton(app.GridLayout3, 'push');
            app.SalvaButton.ButtonPushedFcn = createCallbackFcn(app, @SalvaButtonPushed, true);
            app.SalvaButton.Tooltip = {'Apre una finestra per salvare i dati di log in formato Excel (.xlsx) o CSV. I dati vengono letti dal file temporaneo creato durante il logging.'};
            app.SalvaButton.Text = 'Salva';
            app.DeveloperPanel = uipanel(app.GridLayout);
            app.DeveloperPanel.Title = 'Developer';
            app.GridLayout1 = uigridlayout(app.DeveloperPanel);
            app.Lamp = uilamp(app.GridLayout1);
            app.Lamp.Tooltip = {'Verde: dati ricevuti correttamente. Giallo: pacchetto seriale desincronizzato, possibile rumore sul canale.'};
            app.developermodeCheckBox = uicheckbox(app.GridLayout1);
            app.developermodeCheckBox.Text = 'developer mode';
            app.SerialeBilanciaEditFieldLabel = uilabel(app.GridLayout1);
            app.SerialeBilanciaEditFieldLabel.Text = 'Seriale Bilancia';
            app.SerialeBilanciaEditField = uieditfield(app.GridLayout1, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Scale_Acquisition

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end